classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        FileMenu                       matlab.ui.container.Menu
        RefreshDataMenu                matlab.ui.container.Menu
        TabGroup                       matlab.ui.container.TabGroup
        MainTab                        matlab.ui.container.Tab
        PerformanceManagementIndicatorsPanel  matlab.ui.container.Panel
        RatioofprofitabletradesLabel   matlab.ui.control.Label
        RatioofprofitabletradesGauge   matlab.ui.control.Gauge
        ROIGaugeLabel                  matlab.ui.control.Label
        ROIGauge                       matlab.ui.control.Gauge
        ProfitmarginGaugeLabel         matlab.ui.control.Label
        ProfitmarginGauge              matlab.ui.control.Gauge
        ReportingperiodEditFieldLabel  matlab.ui.control.Label
        ReportingperiodEditField       matlab.ui.control.EditField
        UIAxes                         matlab.ui.control.UIAxes
        RiskManagementIndicatorsPanel  matlab.ui.container.Panel
        FairValueofOptionsmillionAEditFieldLabel  matlab.ui.control.Label
        FairValueofOptionsmillionAEditField  matlab.ui.control.NumericEditField
        NotionalPrincipalofOptionsmillionAEditFieldLabel  matlab.ui.control.Label
        NotionalPrincipalofOptionsmillionAEditField  matlab.ui.control.EditField
        DoDchange1EditFieldLabel       matlab.ui.control.Label
        DoDchange1EditField            matlab.ui.control.EditField
        DoDchange2EditFieldLabel       matlab.ui.control.Label
        DoDchange2EditField            matlab.ui.control.EditField
        LVaRLabel                      matlab.ui.control.Label
        LVaRGauge                      matlab.ui.control.LinearGauge
        DeltaGaugeLabel                matlab.ui.control.Label
        DeltaGauge                     matlab.ui.control.SemicircularGauge
        UIAxes2                        matlab.ui.control.UIAxes
        UIAxes3                        matlab.ui.control.UIAxes
        UIAxes4                        matlab.ui.control.UIAxes
        WPVTab                         matlab.ui.container.Tab
        UITable1                       matlab.ui.control.Table
        RPTTab                         matlab.ui.container.Tab
        UITable2                       matlab.ui.control.Table
        PMTab                          matlab.ui.container.Tab
        UITable3                       matlab.ui.control.Table
        ROITab                         matlab.ui.container.Tab
        UITable4                       matlab.ui.control.Table
        VaRTab                         matlab.ui.container.Tab
        UITable5                       matlab.ui.control.Table
        CVaRTab                        matlab.ui.container.Tab
        UITable6                       matlab.ui.control.Table
        DLBTab                         matlab.ui.container.Tab
        UITable7                       matlab.ui.control.Table
        FVOTab                         matlab.ui.container.Tab
        UITable8                       matlab.ui.control.Table
        NPOTab                         matlab.ui.container.Tab
        UITable9                       matlab.ui.control.Table
        DoD1Tab                        matlab.ui.container.Tab
        UITable10                      matlab.ui.control.Table
        DoD2Tab                        matlab.ui.container.Tab
        UITable11                      matlab.ui.control.Table
        LVaRTab                        matlab.ui.container.Tab
        UITable12                      matlab.ui.control.Table
        DeltaTab                       matlab.ui.container.Tab
        UITable13                      matlab.ui.control.Table
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            % Shortcut fot refresh dashboard
            
            app.RefreshDataMenu.Accelerator = "R";
            
            
            %Reading data from files
            
            data1 = readtable("data1.xlsx", "Sheet", 1);
            app.UITable1.Data = data1;
            
            data2 = readtable("data2.xlsx", "Sheet", 1);
            app.UITable2.Data = data2
            
            data3 = readtable("data3.xlsx", "Sheet", 1);
            app.UITable3.Data = data3
            
            data4 = readtable("data4.xlsx", "Sheet", 1);
            app.UITable4.Data = data4
            
            data5 = readtable("data5.xlsx", "Sheet", 1);
            app.UITable5.Data = data5
            
            data6 = readtable("data6.xlsx", "Sheet", 1);
            app.UITable6.Data = data6
            
            data7 = readtable("data7.xlsx", "Sheet", 1);
            app.UITable7.Data = data7
            
            data8 = readtable("data8.xlsx", "Sheet", 1);
            app.UITable8.Data = data8
            
            data9 = readtable("data9.xlsx", "Sheet", 1);
            app.UITable9.Data = data9
            
            data10 = readtable("data8.xlsx", "Sheet", 3);
            app.UITable10.Data = data10
            
            data11 = readtable("data9.xlsx", "Sheet", 3);
            app.UITable11.Data = data11
            
            data12 = readtable("data10.xlsx", "Sheet", 2);
            app.UITable12.Data = data12
            
            data13 = readtable("data11.xlsx", "Sheet", 1);
            app.UITable13.Data = data13
            
            %Creating the 'Main' panel of the application
            
            bar1=bar(app.UIAxes, app.UITable1.Data.("Date"), app.UITable1.Data.("Value"));
            bar1.FaceColor = '#9f8991';
%             bar1.LineStyle = 'none';
            app.RatioofprofitabletradesGauge.Value = app.UITable2.Data.('x');
            app.ProfitmarginGauge.Value = app.UITable3.Data.('x');
            app.ROIGauge.Value = app.UITable4.Data.('x');
            bar2 = bar(app.UIAxes2, categorical(app.UITable5.Data.("Name")), app.UITable5.Data.("Value"));
            bar2.FaceColor = '#9f8991';
            bar3 = bar(app.UIAxes3, categorical(app.UITable6.Data.("Name")), app.UITable6.Data.("Value"));
            bar3.FaceColor = '#9f8991';
            bar4 = barh(app.UIAxes4, app.UITable7.Data.("Date"), app.UITable7.Data.("Number"));
            bar4.FaceColor = '#9f8991';
            app.FairValueofOptionsmillionAEditField.Value=app.UITable8.Data.('x');
            app.NotionalPrincipalofOptionsmillionAEditField.Value=string(app.UITable9.Data.('x'));
            
            if app.UITable10.Data.('x') >= 0
                app.DoDchange1EditField.BackgroundColor='#8bbf4e'
            else
                app.DoDchange1EditField.BackgroundColor='#f06464'
            end
            app.DoDchange1EditField.Value = string(app.UITable10.Data.('x'));
            
            
            if app.UITable11.Data.('x') >= 0
                app.DoDchange2EditField.BackgroundColor='#8bbf4e'
            else
                app.DoDchange2EditField.BackgroundColor='#f06464'
            end
            app.DoDchange2EditField.Value = string(app.UITable11.Data.('x'));
            
            app.LVaRGauge.Value = app.UITable12.Data.('x');
            
            app.DeltaGauge.Value = app.UITable13.Data.('x');


        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            %             selectedTab = app.TabGroup.SelectedTab;
            %             if selectedTab.Title == "Tab2"
            %                 data1 = readtable("data.xlsx", "Sheet", 1);
            %                 app.UITable1.Data = data1;
            %
            %             end
            %             if selectedTab.Title == "Tab3"
            %                  data2 = readtable("data.xlsx", "Sheet", 1);
            %                 app.UITable2.Data = data2
            %             end
            %             if selectedTab.Title == "Tab4"
            %                  data3 = readtable("data.xlsx", "Sheet", 1);
            %                 app.UITable3.Data = data3
            %             end
            %             if selectedTab.Title == "Tab5"
            %                  data4 = readtable("data.xlsx", "Sheet", 1);
            %                 app.UITable4.Data = data4
            %             end
            %             if selectedTab.Title == "Tab6"
            %                  data5 = readtable("data.xlsx", "Sheet", 1);
            %                 app.UITable5.Data = data5
            %             end
            %             if selectedTab.Title == "Tab7"
            %                  data6 = readtable("data.xlsx", "Sheet", 1);
            %                 app.UITable6.Data = data6
            %             end
            %             if selectedTab.Title == "Tab8"
            %                  data7 = readtable("data.xlsx", "Sheet", 1);
            %                 app.UITable7.Data = data7
            %             end
            %             if selectedTab.Title == "Tab9"
            %                  data8 = readtable("data.xlsx", "Sheet", 1);
            %                 app.UITable8.Data = data8
            %             end
            %             if selectedTab.Title == "Tab10"
            %                  data9 = readtable("data.xlsx", "Sheet", 1);
            %                 app.UITable9.Data = data9
            %             end
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            
            
        end

        % Menu selected function: RefreshDataMenu
        function RefreshDataMenuSelected(app, event)
            
            %Refreshing the dashboard
            
            
            data1 = readtable("data1.xlsx", "Sheet", 1);
            app.UITable1.Data = data1;
            
            data2 = readtable("data2.xlsx", "Sheet", 1);
            app.UITable2.Data = data2
            
            data3 = readtable("data3.xlsx", "Sheet", 1);
            app.UITable3.Data = data3
            
            data4 = readtable("data4.xlsx", "Sheet", 1);
            app.UITable4.Data = data4
            
            data5 = readtable("data5.xlsx", "Sheet", 1);
            app.UITable5.Data = data5
            
            data6 = readtable("data6.xlsx", "Sheet", 1);
            app.UITable6.Data = data6
            
            data7 = readtable("data7.xlsx", "Sheet", 1);
            app.UITable7.Data = data7
            
            data8 = readtable("data8.xlsx", "Sheet", 1);
            app.UITable8.Data = data8
            
            data9 = readtable("data9.xlsx", "Sheet", 1);
            app.UITable9.Data = data9
            
            data10 = readtable("data8.xlsx", "Sheet", 3);
            app.UITable10.Data = data10
            
            data11 = readtable("data9.xlsx", "Sheet", 3);
            app.UITable11.Data = data11
            
            data12 = readtable("data10.xlsx", "Sheet", 2);
            app.UITable12.Data = data12
            
            data13 = readtable("data11.xlsx", "Sheet", 1);
            app.UITable13.Data = data13
            
           
            %Recreating the 'Main' panel of the application
            
            bar1=bar(app.UIAxes, app.UITable1.Data.("Date"), app.UITable1.Data.("Value"));
            bar1.FaceColor = '#9f8991';
            app.RatioofprofitabletradesGauge.Value = app.UITable2.Data.('x');
            app.ProfitmarginGauge.Value = app.UITable3.Data.('x');
            app.ROIGauge.Value = app.UITable4.Data.('x');
            bar2 = bar(app.UIAxes2, categorical(app.UITable5.Data.("Name")), app.UITable5.Data.("Value"));
            bar2.FaceColor = '#9f8991';
            bar3 = bar(app.UIAxes3, categorical(app.UITable6.Data.("Name")), app.UITable6.Data.("Value"));
            bar3.FaceColor = '#9f8991';
            bar4 = barh(app.UIAxes4, app.UITable7.Data.("Date"), app.UITable7.Data.("Number"));
            bar4.FaceColor = '#9f8991';
            app.FairValueofOptionsmillionAEditField.Value=app.UITable8.Data.('x');
            app.NotionalPrincipalofOptionsmillionAEditField.Value=string(app.UITable9.Data.('x'));
            
            
            if app.UITable10.Data.('x') >= 0
                app.DoDchange1EditField.BackgroundColor='#8bbf4e'
            else
                app.DoDchange1EditField.BackgroundColor='#f06464'
            end
            app.DoDchange1EditField.Value = string(app.UITable10.Data.('x'));
            
            
            if app.UITable11.Data.('x') >= 0
                app.DoDchange2EditField.BackgroundColor='#8bbf4e'
            else
                app.DoDchange2EditField.BackgroundColor='#f06464'
            end
            
            app.DoDchange2EditField.Value = string(app.UITable11.Data.('x'));
            
            app.LVaRGauge.Value = app.UITable12.Data.('x');
            
             app.DeltaGauge.Value = app.UITable13.Data.('x');
            
            
            %              bar(app.UIAxes2, app.UITable3.Data.("Date"), app.UITable3.Data.("Value"));
            
            
        end

        % Value changed function: 
        % FairValueofOptionsmillionAEditField
        function FairValueofOptionsmillionAEditFieldValueChanged(app, event)
            
            
        end

        % Value changed function: ReportingperiodEditField
        function ReportingperiodEditFieldValueChanged(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.RefreshDataMenu = uimenu(app.FileMenu);
            app.RefreshDataMenu.MenuSelectedFcn = createCallbackFcn(app, @RefreshDataMenuSelected, true);
            app.RefreshDataMenu.Text = 'Refresh Data';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.MainTab = uitab(app.TabGroup);
            app.MainTab.Title = 'Main';
            app.PerformanceManagementIndicatorsPanel = uipanel(app.MainTab);
            app.PerformanceManagementIndicatorsPanel.Title = 'Performance Management Indicators';
            app.RatioofprofitabletradesLabel = uilabel(app.PerformanceManagementIndicatorsPanel);
            app.RatioofprofitabletradesLabel.Text = 'Ratio of profitable trades (%)';
            app.RatioofprofitabletradesGauge = uigauge(app.PerformanceManagementIndicatorsPanel, 'circular');
            app.ROIGaugeLabel = uilabel(app.PerformanceManagementIndicatorsPanel);
            app.ROIGaugeLabel.Text = 'ROI';
            app.ROIGauge = uigauge(app.PerformanceManagementIndicatorsPanel, 'circular');
            app.ROIGauge.Limits = [0 4];
            app.ProfitmarginGaugeLabel = uilabel(app.PerformanceManagementIndicatorsPanel);
            app.ProfitmarginGaugeLabel.Text = 'Profit margin (%)';
            app.ProfitmarginGauge = uigauge(app.PerformanceManagementIndicatorsPanel, 'circular');
            app.ProfitmarginGauge.Limits = [0 1];
            app.ReportingperiodEditFieldLabel = uilabel(app.PerformanceManagementIndicatorsPanel);
            app.ReportingperiodEditFieldLabel.Text = 'Reporting period:';
            app.ReportingperiodEditField = uieditfield(app.PerformanceManagementIndicatorsPanel, 'text');
            app.ReportingperiodEditField.ValueChangedFcn = createCallbackFcn(app, @ReportingperiodEditFieldValueChanged, true);
            app.ReportingperiodEditField.Editable = 'off';
            app.ReportingperiodEditField.Value = 'december 2003';
            app.UIAxes = uiaxes(app.PerformanceManagementIndicatorsPanel);
            title(app.UIAxes, 'Weekly profit volume (AUD)')
            xlabel(app.UIAxes, 'Date')
            ylabel(app.UIAxes, 'Volume')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.RiskManagementIndicatorsPanel = uipanel(app.MainTab);
            app.RiskManagementIndicatorsPanel.Title = 'Risk Management Indicators';
            app.FairValueofOptionsmillionAEditFieldLabel = uilabel(app.RiskManagementIndicatorsPanel);
            app.FairValueofOptionsmillionAEditFieldLabel.Text = 'Fair Value of Options (million A$)';
            app.FairValueofOptionsmillionAEditField = uieditfield(app.RiskManagementIndicatorsPanel, 'numeric');
            app.FairValueofOptionsmillionAEditField.ValueChangedFcn = createCallbackFcn(app, @FairValueofOptionsmillionAEditFieldValueChanged, true);
            app.FairValueofOptionsmillionAEditField.Editable = 'off';
            app.NotionalPrincipalofOptionsmillionAEditFieldLabel = uilabel(app.RiskManagementIndicatorsPanel);
            app.NotionalPrincipalofOptionsmillionAEditFieldLabel.Text = 'Notional Principal of Options (million A$)';
            app.NotionalPrincipalofOptionsmillionAEditField = uieditfield(app.RiskManagementIndicatorsPanel, 'text');
            app.NotionalPrincipalofOptionsmillionAEditField.Editable = 'off';
            app.DoDchange1EditFieldLabel = uilabel(app.RiskManagementIndicatorsPanel);
            app.DoDchange1EditFieldLabel.Text = 'DoD change1 (%)';
            app.DoDchange1EditField = uieditfield(app.RiskManagementIndicatorsPanel, 'text');
            app.DoDchange1EditField.Editable = 'off';
            app.DoDchange2EditFieldLabel = uilabel(app.RiskManagementIndicatorsPanel);
            app.DoDchange2EditFieldLabel.Text = 'DoD change2 (%)';
            app.DoDchange2EditField = uieditfield(app.RiskManagementIndicatorsPanel, 'text');
            app.DoDchange2EditField.Editable = 'off';
            app.LVaRLabel = uilabel(app.RiskManagementIndicatorsPanel);
            app.LVaRLabel.Text = 'LVaR (%)';
            app.LVaRGauge = uigauge(app.RiskManagementIndicatorsPanel, 'linear');
            app.LVaRGauge.MajorTicks = [0 25 50 75 100];
            app.DeltaGaugeLabel = uilabel(app.RiskManagementIndicatorsPanel);
            app.DeltaGaugeLabel.Text = 'Delta';
            app.DeltaGauge = uigauge(app.RiskManagementIndicatorsPanel, 'semicircular');
            app.DeltaGauge.Limits = [-1 1];
            app.DeltaGauge.MajorTicks = [-1 -0.5 0 0.5 1];
            app.UIAxes2 = uiaxes(app.RiskManagementIndicatorsPanel);
            title(app.UIAxes2, 'VaR')
            xlabel(app.UIAxes2, 'Type')
            ylabel(app.UIAxes2, 'Value (%)')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.RiskManagementIndicatorsPanel);
            title(app.UIAxes3, 'CVaR')
            xlabel(app.UIAxes3, 'Type')
            ylabel(app.UIAxes3, 'Value(%)')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes4 = uiaxes(app.RiskManagementIndicatorsPanel);
            title(app.UIAxes4, 'Daily Limit Breaches')
            xlabel(app.UIAxes4, 'Number of limit breaches')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.WPVTab = uitab(app.TabGroup);
            app.WPVTab.Title = 'WPV';
            app.UITable1 = uitable(app.WPVTab);
            app.UITable1.ColumnName = {'Date'; 'Value'; 'Column 3'; 'Column 4'};
            app.UITable1.RowName = {};
            app.RPTTab = uitab(app.TabGroup);
            app.RPTTab.Title = 'RPT';
            app.UITable2 = uitable(app.RPTTab);
            app.UITable2.ColumnName = {'Ratio of profitable trades'};
            app.UITable2.RowName = {};
            app.PMTab = uitab(app.TabGroup);
            app.PMTab.Title = 'PM';
            app.UITable3 = uitable(app.PMTab);
            app.UITable3.ColumnName = {'Profit Margin'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable3.RowName = {};
            app.ROITab = uitab(app.TabGroup);
            app.ROITab.Title = 'ROI';
            app.UITable4 = uitable(app.ROITab);
            app.UITable4.ColumnName = {'ROI'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable4.RowName = {};
            app.VaRTab = uitab(app.TabGroup);
            app.VaRTab.Title = 'VaR';
            app.UITable5 = uitable(app.VaRTab);
            app.UITable5.ColumnName = {'Type'; 'Value (%)'; 'Column 3'; 'Column 4'};
            app.UITable5.RowName = {};
            app.CVaRTab = uitab(app.TabGroup);
            app.CVaRTab.Title = 'CVaR';
            app.UITable6 = uitable(app.CVaRTab);
            app.UITable6.ColumnName = {'Type'; 'Value(%)'; 'Column 3'; 'Column 4'};
            app.UITable6.RowName = {};
            app.DLBTab = uitab(app.TabGroup);
            app.DLBTab.Title = 'DLB';
            app.UITable7 = uitable(app.DLBTab);
            app.UITable7.ColumnName = {'Number of breaches'; 'Days'; 'Column 3'; 'Column 4'};
            app.UITable7.RowName = {};
            app.FVOTab = uitab(app.TabGroup);
            app.FVOTab.Title = 'FVO';
            app.UITable8 = uitable(app.FVOTab);
            app.UITable8.ColumnName = {'FVO'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable8.RowName = {};
            app.NPOTab = uitab(app.TabGroup);
            app.NPOTab.Title = 'NPO';
            app.UITable9 = uitable(app.NPOTab);
            app.UITable9.ColumnName = {'NPO'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable9.RowName = {};
            app.DoD1Tab = uitab(app.TabGroup);
            app.DoD1Tab.Title = 'DoD1';
            app.UITable10 = uitable(app.DoD1Tab);
            app.UITable10.ColumnName = {'DoD change'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable10.RowName = {};
            app.DoD2Tab = uitab(app.TabGroup);
            app.DoD2Tab.Title = 'DoD2';
            app.UITable11 = uitable(app.DoD2Tab);
            app.UITable11.ColumnName = {'DoD change'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable11.RowName = {};
            app.LVaRTab = uitab(app.TabGroup);
            app.LVaRTab.Title = 'LVaR';
            app.UITable12 = uitable(app.LVaRTab);
            app.UITable12.ColumnName = {'LVaR'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable12.RowName = {};
            app.DeltaTab = uitab(app.TabGroup);
            app.DeltaTab.Title = 'Delta';
            app.UITable13 = uitable(app.DeltaTab);
            app.UITable13.ColumnName = {'Delta'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable13.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end